classdef App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure                    % Positi...
        TabGroup               matlab.ui.container.TabGroup        % Calibr...
        Tab                    matlab.ui.container.Tab             % Calibr...
        Panel                  matlab.ui.container.Panel           % Insert...
        LabelNumericEditField  matlab.ui.control.Label             % Focus ...
        focusLength            matlab.ui.control.NumericEditField  % [-Inf ...
        Label                  matlab.ui.control.Label             % View A...
        viewAngle              matlab.ui.control.NumericEditField  % [-Inf ...
        Label2                 matlab.ui.control.Label             % Chambe...
        chamberWidth           matlab.ui.control.NumericEditField  % [-Inf ...
        Label3                 matlab.ui.control.Label             % Chambe...
        chamberDepth           matlab.ui.control.NumericEditField  % [-Inf ...
        helpFocusLength        matlab.ui.control.Button            % Help
        helpViewAngle          matlab.ui.control.Button            % Help
        helpChamberWidth       matlab.ui.control.Button            % Help
        helpChamberDepth       matlab.ui.control.Button            % Help
        calibrateCameraButton  matlab.ui.control.Button            % Calibrate
        LabelSemicircularGauge matlab.ui.control.Label             % Averag...
        calibrationError       matlab.ui.control.SemicircularGauge % [0 20]
        Tab2                   matlab.ui.container.Tab             % Genera...
        Panel2                 matlab.ui.container.Panel           % Insert...
        Label4                 matlab.ui.control.Label             % Number...
        chamberObjectsNumber   matlab.ui.control.NumericEditField  % [-Inf ...
        helpChamberObjectsNumber matlab.ui.control.Button % Help
        helpRobotDiameter      matlab.ui.control.Button            % Help
        Label5                 matlab.ui.control.Label             % Robot ...
        robotDiameter          matlab.ui.control.NumericEditField  % [-Inf ...
        generetaTopographicMapButton matlab.ui.control.Button % Generate
        Tab3                   matlab.ui.container.Tab             % Start ...
        startMeasurementButton matlab.ui.control.Button            % Start ...
        Panel3                 matlab.ui.container.Panel           % Insert...
        Label6                 matlab.ui.control.Label             % Delta ...
        deltaX                 matlab.ui.control.NumericEditField  % [-Inf ...
        Button2                matlab.ui.control.Button            % Help
        Button3                matlab.ui.control.Button            % Help
        Label7                 matlab.ui.control.Label             % Delta ...
        deltaY                 matlab.ui.control.NumericEditField  % [-Inf ...
        generatePathButton     matlab.ui.control.Button            % Genera...
        connectCameraButton    matlab.ui.control.Button            % Connec...
    end


    properties (Access = private)
        cameras = [] % WebCam Objects
        cameraParameters = [] % Camera Parameters after calibration 
        inputCamerasCalibration = [] % Inputs to calibrate cameras
        inputTopographicMapGeneration = [] % Inputs to generate Topographic Map
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            try
                app.cameraParameters = services.Storage.getCameraParameters(); 
            catch
            end
                
            try
                app.inputCamerasCalibration = services.Storage.getInputCamerasCalibration(); 
                app.focusLength.Value = app.inputCamerasCalibration(1);
                app.viewAngle.Value = app.inputCamerasCalibration(2);
                app.chamberWidth.Value = app.inputCamerasCalibration(3);
                app.chamberDepth.Value = app.inputCamerasCalibration(4);
                app.calibrationError.Value = app.inputCamerasCalibration(5);  
            catch
            end
            
            try
                app.inputTopographicMapGeneration = services.Storage.getInputTopographicMapGeneration(); 
                app.chamberObjectsNumber = app.inputTopographicMapGeneration(1);
                app.robotDiameter = app.inputTopographicMapGeneration(2);
            catch
            end
        end

        % connectCameraButton button pushed function
        function connectCameraButtonButtonPushed(app)
            [m, camerasSize] = size(app.cameras);
            try
                if(camerasSize >= 1)
                    stop(app.cameras(1));
                    delete(app.cameras(1));                    
                end
                if(camerasSize >= 2)
                    stop(app.cameras(2));
                    delete(app.cameras(2));                    
                end
                camerasObj(1) = videoinput('winvideo', 1);
                camerasObj(2) = videoinput('winvideo', 2);
                triggerconfig(camerasObj(1), 'manual');
                src = getselectedsource(camerasObj(2));
                src.FocusMode = 'manual';
                triggerconfig(camerasObj(2), 'manual');
                start(camerasObj(1));
                start(camerasObj(2));
                app.cameras = camerasObj;
                    
%                 preview(app.cameras(1));
                preview(app.cameras(2));
            catch
                if(camerasSize >= 1)
                    stop(app.cameras(1));
                    delete(app.cameras(1));                    
                end
                if(camerasSize >= 2)
                    stop(app.cameras(2));
                    delete(app.cameras(2));                    
                end
                msgbox('Confirm if all cameras aare connected', 'Error', 'error');
            end
        end

        % UIFigure close request function
        function UIFigureCloseRequest(app)
            [m, camerasSize] = size(app.cameras);
            if(camerasSize >= 1)
                stop(app.cameras(1));
                delete(app.cameras(1));                    
            end
            if(camerasSize >= 2)
                stop(app.cameras(2));
                delete(app.cameras(2));                    
            end
            
            app.delete
        end

        % calibrateCameraButton button pushed function
        function calibrateCameraButtonButtonPushed(app)
            app.calibrateCameraButton.Text = 'Calibrating...';
            cameras = classes.Cameras();
            cameras.calibrate(app.focusLength.Value, app.viewAngle.Value*(pi/360), [app.chamberWidth.Value app.chamberDepth.Value]);
            app.calibrateCameraButton.Text = 'Calibrate';
            app.cameraParameters = services.Storage.getCameraParameters();
            app.inputCamerasCalibration = services.Storage.getInputCamerasCalibration();
            
            app.calibrationError.Value = app.inputCamerasCalibration(5);
        end

        % focusLength value changed function
        function focusLengthValueChanged(app)
            app.inputCamerasCalibration(1) = app.focusLength.Value;
        end

        % viewAngle value changed function
        function viewAngleValueChanged(app)
            app.inputCamerasCalibration(2) = app.viewAngle.Value;
        end

        % chamberWidth value changed function
        function chamberWidthValueChanged(app)
            app.inputCamerasCalibration(3) = app.chamberWidth.Value;            
        end

        % chamberDepth value changed function
        function chamberDepthValueChanged(app)
            app.inputCamerasCalibration(4) = app.chamberDepth.Value;
        end

        % startMeasurementButton button pushed function
        function startMeasurementButtonButtonPushed(app)
            [m, camerasSize] = size(app.cameras);
%             try
                if(camerasSize < 2)
                    msgbox('Before start measurement you need to connect cameras'); 
                else
                    Index.startMeasurement(app.cameras); 
                end
%             catch
%                 msgbox('Sorry, some error was found');
%             end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'Positionneur';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest);
            setAutoResize(app, app.UIFigure, true)
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.Units = 'pixels';
            app.Tab = uitab(app.TabGroup);
            app.Tab.Units = 'pixels';
            app.Tab.Title = 'Calibrate Cameras';
            app.Panel = uipanel(app.Tab);
            app.Panel.Title = 'Insert here the input parameters to calibrate cameras';
            app.Panel.Units = 'pixels';
            app.LabelNumericEditField = uilabel(app.Panel);
            app.LabelNumericEditField.Text = 'Focus Length (mm):';
            app.focusLength = uieditfield(app.Panel, 'numeric');
            app.focusLength.ValueChangedFcn = createCallbackFcn(app, @focusLengthValueChanged);
            app.focusLength.ValueDisplayFormat = '%.2f';
            app.Label = uilabel(app.Panel);
            app.Label.Text = 'View Angle (degree):';
            app.viewAngle = uieditfield(app.Panel, 'numeric');
            app.viewAngle.ValueChangedFcn = createCallbackFcn(app, @viewAngleValueChanged);
            app.viewAngle.ValueDisplayFormat = '%11.3g';
            app.Label2 = uilabel(app.Panel);
            app.Label2.Text = 'Chamber Width (mm):';
            app.chamberWidth = uieditfield(app.Panel, 'numeric');
            app.chamberWidth.ValueChangedFcn = createCallbackFcn(app, @chamberWidthValueChanged);
            app.chamberWidth.ValueDisplayFormat = '%11.5g';
            app.Label3 = uilabel(app.Panel);
            app.Label3.Text = 'Chamber Depth (mm):';
            app.chamberDepth = uieditfield(app.Panel, 'numeric');
            app.chamberDepth.ValueChangedFcn = createCallbackFcn(app, @chamberDepthValueChanged);
            app.chamberDepth.ValueDisplayFormat = '%11.5g';
            app.helpFocusLength = uibutton(app.Panel, 'push');
            app.helpFocusLength.Text = 'Help';
            app.helpViewAngle = uibutton(app.Panel, 'push');
            app.helpViewAngle.Text = 'Help';
            app.helpChamberWidth = uibutton(app.Panel, 'push');
            app.helpChamberWidth.Text = 'Help';
            app.helpChamberDepth = uibutton(app.Panel, 'push');
            app.helpChamberDepth.Text = 'Help';
            app.calibrateCameraButton = uibutton(app.Tab, 'push');
            app.calibrateCameraButton.ButtonPushedFcn = createCallbackFcn(app, @calibrateCameraButtonButtonPushed);
            app.calibrateCameraButton.Text = 'Calibrate';
            app.LabelSemicircularGauge = uilabel(app.Tab);
            app.LabelSemicircularGauge.Text = 'Average Calibration Error (mm)';
            app.calibrationError = uigauge(app.Tab, 'semicircular');
            app.calibrationError.Limits = [0 20];
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Units = 'pixels';
            app.Tab2.Title = 'Generate Topographic Map';
            app.Panel2 = uipanel(app.Tab2);
            app.Panel2.Title = 'Insert here the input parameters to calibrate cameras';
            app.Panel2.Units = 'pixels';
            app.Label4 = uilabel(app.Panel2);
            app.Label4.Text = 'Number of objects in the chamber:';
            app.chamberObjectsNumber = uieditfield(app.Panel2, 'numeric');
            app.chamberObjectsNumber.ValueDisplayFormat = '%11.2g';
            app.helpChamberObjectsNumber = uibutton(app.Panel2, 'push');
            app.helpChamberObjectsNumber.Text = 'Help';
            app.helpRobotDiameter = uibutton(app.Panel2, 'push');
            app.helpRobotDiameter.Text = 'Help';
            app.Label5 = uilabel(app.Panel2);
            app.Label5.Text = 'Robot Diameter (mm):';
            app.robotDiameter = uieditfield(app.Panel2, 'numeric');
            app.robotDiameter.ValueDisplayFormat = '%11.3g';
            app.generetaTopographicMapButton = uibutton(app.Tab2, 'push');
            app.generetaTopographicMapButton.Text = 'Generate';
            app.Tab3 = uitab(app.TabGroup);
            app.Tab3.Units = 'pixels';
            app.Tab3.Title = 'Start Measument';
            app.startMeasurementButton = uibutton(app.Tab3, 'push');
            app.startMeasurementButton.ButtonPushedFcn = createCallbackFcn(app, @startMeasurementButtonButtonPushed);
            app.startMeasurementButton.Text = 'Start Measurement';
            app.Panel3 = uipanel(app.Tab3);
            app.Panel3.Title = 'Insert here the input parameters to realize the mesurement';
            app.Panel3.Units = 'pixels';
            app.Label6 = uilabel(app.Panel3);
            app.Label6.Text = 'Delta X (mm):';
            app.deltaX = uieditfield(app.Panel3, 'numeric');
            app.deltaX.ValueDisplayFormat = '%.1f';
            app.Button2 = uibutton(app.Panel3, 'push');
            app.Button2.Text = 'Help';
            app.Button3 = uibutton(app.Panel3, 'push');
            app.Button3.Text = 'Help';
            app.Label7 = uilabel(app.Panel3);
            app.Label7.Text = 'Delta Y (mm):';
            app.deltaY = uieditfield(app.Panel3, 'numeric');
            app.deltaY.ValueDisplayFormat = '%.1f';
            app.generatePathButton = uibutton(app.Tab3, 'push');
            app.generatePathButton.Text = 'Generate Path';
            app.connectCameraButton = uibutton(app.UIFigure, 'push');
            app.connectCameraButton.ButtonPushedFcn = createCallbackFcn(app, @connectCameraButtonButtonPushed);
            app.connectCameraButton.Text = 'Connect Cameras';
        end
    end

    methods (Access = public)

        % Construct app
        function app = App()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end